classdef SpaceETAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        PlaygamesButton        matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        TimeETASButton         matlab.ui.control.Button
        LoadingData            matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        PrintLabel             matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        DrawingTab             matlab.ui.container.Tab
        Image                  matlab.ui.control.Image
        MappingButton          matlab.ui.control.Button
        Drawing                matlab.ui.control.UIAxes
        CalMcTab               matlab.ui.container.Tab
        GridsEditField         matlab.ui.control.NumericEditField
        GridsEditField_3Label  matlab.ui.control.Label
        npEditField            matlab.ui.control.NumericEditField
        npEditField_3Label     matlab.ui.control.Label
        McMappingButton        matlab.ui.control.Button
        GridMapping            matlab.ui.control.UIAxes
        McMapping              matlab.ui.control.UIAxes
        CalbTab                matlab.ui.container.Tab
        bMappingButton         matlab.ui.control.Button
        GridsEditField_2       matlab.ui.control.NumericEditField
        GridsEditField_2Label  matlab.ui.control.Label
        npEditField_2          matlab.ui.control.NumericEditField
        npEditField_2Label     matlab.ui.control.Label
        GridMapping2           matlab.ui.control.UIAxes
        bMapping               matlab.ui.control.UIAxes
        AnalysisTab            matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadingData
        function LoadingDataPushed(app, event)
            helpdlg(['First one is initial-data which including ' ...
                '(lon-lat-time-mag) and another is some setting'],'Notice');
            [filename,~] = uigetfile(...
                {'*.txt;*.csv;*.xlsx;*.mat', '数据文件 (*.txt, *.csv, *.xlsx, *.mat)';
                '*.txt', '文本文件 (*.txt)';
                '*.csv', 'CSV文件 (*.csv)';
                '*.xlsx', 'Excel文件 (*.xlsx)';
                '*.mat', 'MAT文件 (*.mat)';
                '*.*', '所有文件 (*.*)'}, ...
                '选择地震数据文件');
            [Rangefile,~] = uigetfile(...
                {'*.txt;*.csv;*.xlsx;*.mat', '数据文件 (*.txt, *.csv, *.xlsx, *.mat)';
                '*.txt', '文本文件 (*.txt)';
                '*.csv', 'CSV文件 (*.csv)';
                '*.xlsx', 'Excel文件 (*.xlsx)';
                '*.mat', 'MAT文件 (*.mat)';
                '*.*', '所有文件 (*.*)'}, ...
                '选择地震数据文件');
%             filename='alljpM45.txt';
            if isequal(filename, 0) 
                app.PrintLabel.Text = 'Fail loading';
%                 return;
            else
                Button_LoadingData(app.PrintLabel,filename,Rangefile);
            end
        end

        % Callback function
        function InputrangeEditFieldValueChanged(app, event)
            value = app.InputrangeEditField.Value;
            douvalue=str2double(value);
            douvalue=douvalue+1;
            strvalue=num2str(douvalue);
            app.PrintLabel.Text=strvalue;
        end

        % Button down function: Drawing
        function DrawingButtonDown(app, event)

        end

        % Button pushed function: MappingButton
        function MappingButtonPushed(app, event)
            global lon lat time mag  R
            axdraw=app.Drawing;
            Space_Drawing(app.Drawing,lon,lat,mag,time,'Time (days)', ...
                'redshift');
            hold(app.Drawing,"on");
            plot(app.Drawing,[R(:,1);R(1,1)],[R(:,2);R(1,2)],'LineWidth',2, ...
                'Color','r','LineStyle','--');
            title(axdraw,''); xlabel(axdraw,'lon'); ylabel(axdraw,'lat');
            hold(axdraw,'off');
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure); 
        end

        % Button down function: McMapping
        function McMappingButtonDown(app, event)
            
        end

        % Button pushed function: McMappingButton
        function McMappingButtonPushed(app, event)
            Button_Spacewindow_Mcvalue(app.McMapping,app.GridMapping,'rainbow',true);
        end

        % Value changed function: npEditField
        function npEditFieldValueChanged(app, event)
%             clear McBWD
            global McBWD
            McBWD = (app.npEditField.Value);
        end

        % Value changed function: GridsEditField
        function GridsEditFieldValueChanged(app, event)
%             clear McGrids
            global McGrids
            McGrids = (app.GridsEditField.Value);            
        end

        % Button pushed function: bMappingButton
        function bMappingButtonPushed(app, event)
            Button_Spacewindow_bvalue(app.bMapping,app.GridMapping2,'rainbow',true);
        end

        % Value changed function: npEditField_2
        function npEditField_2ValueChanged(app, event)
            clear bBWD
            global bBWD
            bBWD = (app.npEditField_2.Value);
        end

        % Value changed function: GridsEditField_2
        function GridsEditField_2ValueChanged(app, event)
            clear bGrids
            global bGrids
            bGrids = (app.GridsEditField_2.Value);
            
        end

        % Button down function: bMapping
        function bMappingButtonDown(app, event)
            
        end

        % Button down function: GridMapping2
        function GridMapping2ButtonDown(app, event)
            
        end

        % Button pushed function: TimeETASButton
        function TimeETASButtonPushed(app, event)
            run TimeETASAPP.mlapp
        end

        % Button pushed function: PlaygamesButton
        function PlaygamesButtonPushed(app, event)
            Button_Dragon();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {756, 756};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {160, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadingData = uibutton(app.LeftPanel, 'push');
            app.LoadingData.ButtonPushedFcn = createCallbackFcn(app, @LoadingDataPushed, true);
            app.LoadingData.Text = 'LoadingData';
            app.TimeETASButton = uibutton(app.LeftPanel, 'push');
            app.TimeETASButton.ButtonPushedFcn = createCallbackFcn(app, @TimeETASButtonPushed, true);
            app.TimeETASButton.Text = 'Time-ETAS';
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PlaygamesButton = uibutton(app.LeftPanel, 'push');
            app.PlaygamesButton.ButtonPushedFcn = createCallbackFcn(app, @PlaygamesButtonPushed, true);
            app.PlaygamesButton.Text = 'Play games';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DrawingTab = uitab(app.TabGroup);
            app.DrawingTab.Title = 'Drawing';
            app.Drawing = uiaxes(app.DrawingTab);
            xlabel(app.Drawing, 'lon')
            ylabel(app.Drawing, 'lat')
            zlabel(app.Drawing, 'depth')
            app.Drawing.ButtonDownFcn = createCallbackFcn(app, @DrawingButtonDown, true);
            app.MappingButton = uibutton(app.DrawingTab, 'push');
            app.MappingButton.ButtonPushedFcn = createCallbackFcn(app, @MappingButtonPushed, true);
            app.MappingButton.Text = 'Mapping';
            app.Image = uiimage(app.DrawingTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'APPDesign', 'Symbol', '屏幕截图 2025-08-05 223842.png');
            app.CalMcTab = uitab(app.TabGroup);
            app.CalMcTab.Title = 'Cal-Mc';
            app.McMapping = uiaxes(app.CalMcTab);
            xlabel(app.McMapping, 'lon')
            ylabel(app.McMapping, 'lat')
            app.McMapping.ButtonDownFcn = createCallbackFcn(app, @McMappingButtonDown, true);
            app.GridMapping = uiaxes(app.CalMcTab);
            xlabel(app.GridMapping, 'lon')
            ylabel(app.GridMapping, 'lat')
            app.McMappingButton = uibutton(app.CalMcTab, 'push');
            app.McMappingButton.ButtonPushedFcn = createCallbackFcn(app, @McMappingButtonPushed, true);
            app.McMappingButton.Icon = fullfile(pathToMLAPP, 'APPDesign', 'Symbol', '屏幕截图 2025-08-05 224023.png');
            app.McMappingButton.Text = 'Mc-Mapping';
            app.npEditField_3Label = uilabel(app.CalMcTab);
            app.npEditField_3Label.Text = 'np';
            app.npEditField = uieditfield(app.CalMcTab, 'numeric');
            app.npEditField.ValueChangedFcn = createCallbackFcn(app, @npEditFieldValueChanged, true);
            app.npEditField.Value = 50;
            app.GridsEditField_3Label = uilabel(app.CalMcTab);
            app.GridsEditField_3Label.Text = 'Grids';
            app.GridsEditField = uieditfield(app.CalMcTab, 'numeric');
            app.GridsEditField.ValueChangedFcn = createCallbackFcn(app, @GridsEditFieldValueChanged, true);
            app.GridsEditField.Value = 25;
            app.CalbTab = uitab(app.TabGroup);
            app.CalbTab.Title = 'Cal-b';
            app.bMapping = uiaxes(app.CalbTab);
            xlabel(app.bMapping, 'lon')
            ylabel(app.bMapping, 'lat')
            zlabel(app.bMapping, 'Z')
            app.bMapping.ButtonDownFcn = createCallbackFcn(app, @bMappingButtonDown, true);
            app.GridMapping2 = uiaxes(app.CalbTab);
            xlabel(app.GridMapping2, 'lon')
            ylabel(app.GridMapping2, 'lat')
            zlabel(app.GridMapping2, 'Z')
            app.GridMapping2.ButtonDownFcn = createCallbackFcn(app, @GridMapping2ButtonDown, true);
            app.npEditField_2Label = uilabel(app.CalbTab);
            app.npEditField_2Label.Text = 'np';
            app.npEditField_2 = uieditfield(app.CalbTab, 'numeric');
            app.npEditField_2.ValueChangedFcn = createCallbackFcn(app, @npEditField_2ValueChanged, true);
            app.npEditField_2.Value = 50;
            app.GridsEditField_2Label = uilabel(app.CalbTab);
            app.GridsEditField_2Label.Text = 'Grids';
            app.GridsEditField_2 = uieditfield(app.CalbTab, 'numeric');
            app.GridsEditField_2.ValueChangedFcn = createCallbackFcn(app, @GridsEditField_2ValueChanged, true);
            app.GridsEditField_2.Value = 25;
            app.bMappingButton = uibutton(app.CalbTab, 'push');
            app.bMappingButton.ButtonPushedFcn = createCallbackFcn(app, @bMappingButtonPushed, true);
            app.bMappingButton.Icon = fullfile(pathToMLAPP, 'APPDesign', 'Symbol', '屏幕截图 2025-08-05 224023.png');
            app.bMappingButton.Text = 'b-Mapping';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.PrintLabel = uilabel(app.RightPanel);
            app.PrintLabel.Text = 'Print';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpaceETAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end